classdef main < matlab.apps.AppBase

        % Properties that correspond to app components
        properties (Access = public)
                UIFigure                  matlab.ui.Figure
                IdentificationModeButton  matlab.ui.control.Button
                InteractivePanel          matlab.ui.container.Panel
                UIAxesLabel               matlab.ui.control.Label
                PersonListDropDown        matlab.ui.control.DropDown
                PersonListDropDownLabel   matlab.ui.control.Label
                GoBackButton              matlab.ui.control.Button
                StartButton               matlab.ui.control.Button
                InputEditField            matlab.ui.control.EditField
                InputEditFieldLabel       matlab.ui.control.Label
                OutputLabel               matlab.ui.control.Label
                UIAxes                    matlab.ui.control.UIAxes
                VerificationModeButton    matlab.ui.control.Button
                RegisterYourselvesButton  matlab.ui.control.Button
                FaceRecognitionAppLabel   matlab.ui.control.Label
        end

        properties (Access = public)
                operation

                faceDatabase
                training
                test

                personIndex
                faceClDefined
                faceClassifier
                trainingFeatures        % trainingFeatures are evaluated at
                                        % startup over the collected database
                trainingLabel           % label of associated person
                dbSize
                ax
                videoFrame
                Camera % webcam stream object
                himg % image object handle

                noseResizedSize
                eyesResizedSize
                mouthResizedSize
                
                noseFeatureSize
                eyesFeatureSize
                mouthFeatureSize

                featureSize

                NoseDetect
                ndPresent = 0
                EyesDetect
                edPresent = 0
                MouthDetect
                mdPresent = 0


                defaultImSize = [112 92]
                defaultCellSize = [6 6]
    end
        

        % Callbacks that handle component events
        methods (Access = private)

                % Code that executes after component creation
                function startupFcn(app)
                        [res] = ttSystem(app);
                        if (res < 0)
                                msgbox(['An error occured while Training and Testing the System,' ...
                                        ' try running the app again']);
                                return;
                        end
                end

                % Button pushed function: RegisterYourselvesButton
                function RegisterYourselvesButtonPushed(app, event)
                        app.operation = 'registration';
                        restoreDefaultPanel(app);
                        updateRegisterPanel(app);
                end

                % Close request function: UIFigure
                function UIFigureCloseRequest(app, event)
                        delete(app);
                end

                % Button pushed function: StartButton
                function StartButtonPushed(app, event)
                        callRoutine(app);                        
                end

                % Button pushed function: VerificationModeButton
                function VerificationModeButtonPushed(app, event)
                        restoreDefaultPanel(app);
                        app.operation = 'verification';
                        updateRegisterPanel(app);
                end

                % Button pushed function: IdentificationModeButton
                function IdentificationModeButtonPushed(app, event)
                        restoreDefaultPanel(app);
                        app.operation = 'identification';
                        updateRegisterPanel(app);
                end

                % Button pushed function: GoBackButton
                function GoBackButtonPushed(app, event)
                        restoreDefaultPanel(app);
                end
        end

        % Component initialization
        methods (Access = private)

                % Create UIFigure and components
                function createComponents(app)
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                        app.UIFigure = uifigure('Visible', 'off');
                        app.UIFigure.Name = 'MATLAB App';
                        app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
                        app.FaceRecognitionAppLabel = uilabel(app.UIFigure);
                        app.FaceRecognitionAppLabel.Text = 'Face Recognition App';
                        app.RegisterYourselvesButton = uibutton(app.UIFigure, 'push');
                        app.RegisterYourselvesButton.ButtonPushedFcn = createCallbackFcn(app, @RegisterYourselvesButtonPushed, true);
                        app.RegisterYourselvesButton.Text = 'Register Yourselves';
                        app.VerificationModeButton = uibutton(app.UIFigure, 'push');
                        app.VerificationModeButton.ButtonPushedFcn = createCallbackFcn(app, @VerificationModeButtonPushed, true);
                        app.VerificationModeButton.Text = 'Verification Mode';
                        app.InteractivePanel = uipanel(app.UIFigure);
                        app.InteractivePanel.Title = 'Insert your input within the field below';
                        app.InteractivePanel.Visible = 'off';
                        app.UIAxes = uiaxes(app.InteractivePanel);
                        app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
                        app.OutputLabel = uilabel(app.InteractivePanel);
                        app.OutputLabel.Text = '';
                        app.InputEditFieldLabel = uilabel(app.InteractivePanel);
                        app.InputEditFieldLabel.Text = 'Enter Your Name:';
                        app.InputEditField = uieditfield(app.InteractivePanel, 'text');
                        app.StartButton = uibutton(app.InteractivePanel, 'push');
                        app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
                        app.StartButton.Text = 'Start';
                        app.GoBackButton = uibutton(app.InteractivePanel, 'push');
                        app.GoBackButton.ButtonPushedFcn = createCallbackFcn(app, @GoBackButtonPushed, true);
                        app.GoBackButton.Text = 'Go Back';
                        app.PersonListDropDownLabel = uilabel(app.InteractivePanel);
                        app.PersonListDropDownLabel.Visible = 'off';
                        app.PersonListDropDownLabel.Text = 'Person List';
                        app.PersonListDropDown = uidropdown(app.InteractivePanel);
                        app.PersonListDropDown.Items = {};
                        app.PersonListDropDown.Visible = 'off';
                        app.PersonListDropDown.Value = {};
                        app.UIAxesLabel = uilabel(app.InteractivePanel);
                        app.IdentificationModeButton = uibutton(app.UIFigure, 'push');
                        app.IdentificationModeButton.ButtonPushedFcn = createCallbackFcn(app, @IdentificationModeButtonPushed, true);
                        app.IdentificationModeButton.Text = 'Identification Mode';
                        % Show the figure after all components are created
                        app.UIFigure.Visible = 'on';
                end
        end

        % App creation and deletion
        methods (Access = public)

                % Construct app
                function app = main

                        % Create UIFigure and components
                        createComponents(app)

                        % Register the app with App Designer
                        registerApp(app, app.UIFigure)

                        % Execute the startup function
                        runStartupFcn(app, @startupFcn)

                        if nargout == 0
                                clear app
                        end
                end

                % Code that executes before app deletion
                function delete(app)

                        % Delete UIFigure when app is deleted
                        delete(app.UIFigure)
                end
        end
end